classdef main_R2018b < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        TabGroup       matlab.ui.container.TabGroup
        Tab            matlab.ui.container.Tab
        UITable        matlab.ui.control.Table
        SaveButton     matlab.ui.control.Button
        PlotButton     matlab.ui.control.Button
        ClearButton    matlab.ui.control.Button
        LoadButton     matlab.ui.control.Button
        TextAreaLabel  matlab.ui.control.Label
        TextArea       matlab.ui.control.TextArea
        Tab2           matlab.ui.container.Tab
    end

    
    properties (Access = private)
        DataHandle % Description
        DataAnalyzer
        
        TableConfig
    end
    
    methods (Access = private)
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.TableConfig.selCol = 1;
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            indices = event.Indices;
            if (indices(2) == app.TableConfig.selCol)
                app.UITable.Data(indices(1),app.TableConfig.selCol) = ~ app.UITable.Data(indices(1),app.TableConfig.selCol);
            end
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            % open dummy figure
            f=figure();
            %bring figure to front focus
            drawnow;
            
            dir = [pwd, ''];
            [file, path] = uigetfile(fullfile(dir,'*.**'),'Choose Flight Log \n');
            if ~file
                fprintf('No Log file selected!\n');
            else
                % Select the log file
                fprintf('Load flight log %s!\n', file);
                tmp = load(file);
                app.DataHandle = tmp.data_table;
                
                % Table: assign the fieldname and the selection list
                fNames = fieldnames(app.DataHandle);
                n = numel(fNames);
                app.UITable.Data(1:n,app.TableConfig.selCol) = false;
                app.UITable.ColumnFormat = {'logical'};
                app.UITable.RowName = fNames;
            end
            
            delete(f);
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.UITable = uitable(app.Tab);
            app.UITable.ColumnName = {'Select'};
            app.UITable.RowName = {};
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.SaveButton = uibutton(app.Tab, 'push');
            app.SaveButton.Text = 'Save';
            app.PlotButton = uibutton(app.Tab, 'push');
            app.PlotButton.Text = 'Plot';
            app.ClearButton = uibutton(app.Tab, 'push');
            app.ClearButton.Text = 'Clear';
            app.LoadButton = uibutton(app.Tab, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.TextAreaLabel = uilabel(app.Tab);
            app.TextAreaLabel.Text = 'Text Area';
            app.TextArea = uitextarea(app.Tab);
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
        end
    end

    methods (Access = public)

        % Construct app
        function app = main_R2018b

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end